classdef badanie2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        HzLabel            matlab.ui.control.Label
        GonoDropDown       matlab.ui.control.DropDown
        GonoDropDownLabel  matlab.ui.control.Label
        slysze             matlab.ui.control.Button
        force_STOP         matlab.ui.control.StateButton
        czestotlwosc       matlab.ui.control.Label
    end

    
    properties (Access = public)
        amplituda  = [1
                    0.916666667
                    0.833333333
                    0.75
                    0.666666667
                    0.583333333
                    0.5
                    0.416666667
                    0.333333333
                    0.25
                    0.166666667
                    0.01
                    ];
        amplituda_db = [120 110 100 90 80 70 60 50 40 30 20 10];
        freq_tone = [125,500,1000,2000,3000,4000,6000,8000,10000];
        pomiar_up= [0 0 0 0 0 0 0 0 0];
        pomiar_ul= [0 0 0 0 0 0 0 0 0];
        popup = 1;
        freq_active = 1;
        amp_active = 1;
        state = 0; % 0 - lewe ; 1 - prawe; 2 - koniec(wyrysowanie wykresu+krypto_reklama)
    end
    
    methods (Access = public)
        
        function generowanie_tonu(app, czestotliwosc, amplituda, ucho)
            % ucho = 0 - lewe ; ucho = 1 - prawe
            SoundVolume(amplituda);
            Fs=48000;
            Ts = 1/Fs;
            T=0:Ts:(Fs*Ts);
            envilope = sin(pi*T/T(length(T)));
            Y=sin(2*pi*czestotliwosc*T);
            Y=Y.*envilope;
            if ucho == 0 %lewe
               Wyj=[Y;zeros(size(T))]'; 
            end
            if ucho == 1 %prawe
                Wyj=[zeros(size(T)); Y]';
            end
            sound(Wyj,Fs);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.popup == 1
                waitfor(msgbox('Zaraz rozpocznie się badanie lewego ucha','Uwaga!'));
                pause(2);
            end    
            switch app.state
                case 0
                   app.generowanie_tonu(app.freq_tone(app.freq_active), app.amplituda(app.amp_active),0);
                case 1
                    app.generowanie_tonu(app.freq_tone(app.freq_active), app.amplituda(app.amp_active),1);
                case 2
                    dane = [ -10 0 10 20 30 40 50 50 60 80 90 110 110];
                    freq_gen = [125,500,1000,2000,3000,4000,6000,8000,10000];
                    figure('Position',[0 0 1280 720])
                    plot(freq_gen,app.pomiar_ul,'green')
                    set(gca, 'ydir', 'reverse');
                     title('Audiogram - zobrazowanie progów słyszenia dźwięku');
                     xlabel('Częstotliwość w Hz');
                     ylabel('Ubytek słuchu w dB');
                     xlim([125 10000]);
                     ylim([-10 130]);
                     xticks([125,500,1000,2000,3000,4000,6000,8000,10000]);
                     yticks([ -10 0 10 30 50 60 80 90 110 130]);
                    hold on;
                    plot(freq_gen,app.pomiar_up,'red')
                    legend('ucho lewe','ucho prawe');
            end
            
        end

        % Value changed function: force_STOP
        function force_STOPValueChanged(app, event)
            if app.force_STOP.Value == true 
                app.amplituda = 0;      
            else
                app.amplituda  = 10./(10.^((3/20)*(0:11)));
            end
        end

        % Value changed function: GonoDropDown
        function GonoDropDownValueChanged(app, event)
            value = app.GonoDropDown.Value;
            app.popup = 0;
            switch value
                case '10 dB'
                    app.amp_active = 12;
                case '20 dB'
                    app.amp_active = 11;
                case '30 dB'
                    app.amp_active = 10;
                case '40 dB'
                    app.amp_active = 9;
                case "50 dB"
                    app.amp_active = 8;
                case "60 dB"
                    app.amp_active = 7;
                case "70 dB"
                    app.amp_active = 6;
                case "80 dB"
                    app.amp_active = 5;
                case "90 dB"
                    app.amp_active = 4;
                case "100 dB"
                    app.amp_active = 3;
                case "110 dB"
                    app.amp_active = 2;
                case "120 dB"
                    app.amp_active = 1;    
            end
            startupFcn(app);
        end

        % Button pushed function: slysze
        function slyszeButtonPushed(app, event)
            if app.freq_active == 9 && app.state == 0
                app.state = 1;
                app.freq_active = 1;
                waitfor(msgbox('Zaraz rozpocznie się badanie prawego ucha','Uwaga!'));
                pause(1);
                textLabel = sprintf('%d', app.freq_tone(app.freq_active));
                app.czestotlwosc.Text = textLabel;
                startupFcn(app);
            elseif app.freq_active == 9 && app.state == 1
                app.state = 2;
                app.freq_active = 1;
                app.czestotlwosc.Text = "Wyniki";
                startupFcn(app);
            else
                app.popup = 0;
                if app.state == 0
                    app.pomiar_ul(app.freq_active) = app.amplituda_db(app.amp_active);
                elseif app.state == 1
                    app.pomiar_up(app.freq_active) = app.amplituda_db(app.amp_active);
                end    
                app.freq_active = app.freq_active + 1;
                textLabel = sprintf('%d', app.freq_tone(app.freq_active));
                app.czestotlwosc.Text = textLabel;
                startupFcn(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.czestotlwosc = uilabel(app.UIFigure);
            app.czestotlwosc.Text = {'125'; ''};
            app.force_STOP = uibutton(app.UIFigure, 'state');
            app.force_STOP.ValueChangedFcn = createCallbackFcn(app, @force_STOPValueChanged, true);
            app.force_STOP.Text = 'WYCISZ';
            app.slysze = uibutton(app.UIFigure, 'push');
            app.slysze.ButtonPushedFcn = createCallbackFcn(app, @slyszeButtonPushed, true);
            app.slysze.Text = {'Usłyszałem dźwięk'; ''};
            app.GonoDropDownLabel = uilabel(app.UIFigure);
            app.GonoDropDownLabel.Text = 'Głośność';
            app.GonoDropDown = uidropdown(app.UIFigure);
            app.GonoDropDown.Items = {'10 dB', '20 dB', '30 dB', '40 dB', '50 dB', '60 dB', '70 dB', '80 dB', '90 dB', '100 dB', '110 dB', '120 dB'};
            app.GonoDropDown.ValueChangedFcn = createCallbackFcn(app, @GonoDropDownValueChanged, true);
            app.GonoDropDown.Value = '20 dB';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = badanie2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end